classdef Spectrum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrumHAMKkuzarUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GeneratorPanel             matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        Fq1Tab                     matlab.ui.container.Tab
        AmpEditFieldLabel          matlab.ui.control.Label
        Amp1                       matlab.ui.control.NumericEditField
        FreqLabel                  matlab.ui.control.Label
        Freq1                      matlab.ui.control.NumericEditField
        Fq2Tab                     matlab.ui.container.Tab
        AmpEditField_2Label        matlab.ui.control.Label
        Amp2                       matlab.ui.control.NumericEditField
        FreqEditField_2Label       matlab.ui.control.Label
        Freq2                      matlab.ui.control.NumericEditField
        Fq3Tab                     matlab.ui.container.Tab
        AmpEditField_3Label        matlab.ui.control.Label
        Amp3                       matlab.ui.control.NumericEditField
        FreqEditField_3Label       matlab.ui.control.Label
        Freq3                      matlab.ui.control.NumericEditField
        Fq4Tab                     matlab.ui.container.Tab
        AmpEditField_4Label        matlab.ui.control.Label
        Amp4                       matlab.ui.control.NumericEditField
        FreqEditField_4Label       matlab.ui.control.Label
        Freq4                      matlab.ui.control.NumericEditField
        Fq5Tab                     matlab.ui.container.Tab
        AmpEditField_4Label_2      matlab.ui.control.Label
        Amp5                       matlab.ui.control.NumericEditField
        FreqEditField_4Label_2     matlab.ui.control.Label
        Freq5                      matlab.ui.control.NumericEditField
        GenStBtn                   matlab.ui.control.StateButton
        NoiseCheckBox              matlab.ui.control.CheckBox
        SampleRateDropDownLabel    matlab.ui.control.Label
        SampleRate                 matlab.ui.control.DropDown
        NoiseSliderLabel           matlab.ui.control.Label
        NoiseSlider                matlab.ui.control.Slider
        waveshapeDropDownLabel     matlab.ui.control.Label
        waveshape                  matlab.ui.control.DropDown
        timesSliderLabel           matlab.ui.control.Label
        timesSlider                matlab.ui.control.Slider
        TakeaShotBtn               matlab.ui.control.Button
        FitlersPanel               matlab.ui.container.Panel
        txtHighPass                matlab.ui.control.NumericEditField
        FilterTypeButtonGroup      matlab.ui.container.ButtonGroup
        HighPassButton             matlab.ui.control.RadioButton
        LowPassButton              matlab.ui.control.RadioButton
        NoneButton                 matlab.ui.control.RadioButton
        FilterConstKnobLabel       matlab.ui.control.Label
        FilterConstKnob            matlab.ui.control.Knob
        RightPanel                 matlab.ui.container.Panel
        TimeUIAxespp               matlab.ui.control.UIAxes
        SpecUIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        amplifyIndex;
    end
    
    methods (Access = private)
        
        function [freq ampt samplerate time noise type] = getAllValues(app)
             value = app.waveshape.Value;
             switch (value)
                case 'Sine'
                      freq       = [app.Freq1.Value app.Freq2.Value app.Freq3.Value app.Freq4.Value app.Freq5.Value];
                      ampt       = [app.Amp1.Value app.Amp2.Value app.Amp3.Value app.Amp4.Value app.Amp5.Value];
                      samplerate = str2num(app.SampleRate.Value);
                      time       = 0:1/samplerate:app.timesSlider.Value;
                      noise      = app.NoiseCheckBox.Value;
                      type       = app.waveshape.Value;
                case 'Square'
                      freq       = [app.Freq1.Value app.Freq2.Value ];
                      ampt       = [app.Amp1.Value app.Amp2.Value ];
                      samplerate = str2num(app.SampleRate.Value);
                      time       = 0:1/samplerate:app.timesSlider.Value;
                      noise      = app.NoiseCheckBox.Value;
                      type       = app.waveshape.Value;
                 case 'Triangle' 
                      freq       = [app.Freq1.Value app.Freq2.Value];
                      ampt       = [app.Amp1.Value app.Amp2.Value ];
                      samplerate = str2num(app.SampleRate.Value);
                      time       = 0:1/samplerate:app.timesSlider.Value;
                      noise      = app.NoiseCheckBox.Value;
                      type       = app.waveshape.Value;
                 case 'Saw' 
                      freq       = [app.Freq1.Value app.Freq2.Value];
                      ampt       = [app.Amp1.Value app.Amp2.Value];
                      samplerate = str2num(app.SampleRate.Value);
                      time       = 0:1/samplerate:app.timesSlider.Value;
                      noise      = app.NoiseCheckBox.Value;
                      type       = app.waveshape.Value;
                otherwise
                  
            end
        end
        
        function results = changeInputEnableStatue(app)
            value = app.waveshape.Value;
             switch (value)

                case 'Square'
                    app.Freq3.Enable = false;
                    app.Freq4.Enable = false;
                    app.Freq5.Enable = false;
                    
                    app.Amp3.Enable = false;
                    app.Amp4.Enable = false;
                    app.Amp5.Enable = false;
                    
                 case 'Triangle'  
                    %app.Freq2.Enable = false;
                    app.Freq3.Enable = false;
                    app.Freq4.Enable = false;
                    app.Freq5.Enable = false;
                    
                    %app.Amp2.Enable = false;
                    app.Amp3.Enable = false;
                    app.Amp4.Enable = false;
                    app.Amp5.Enable = false;
                 case 'Saw'
                    %app.Freq2.Enable = false;
                    app.Freq3.Enable = false;
                    app.Freq4.Enable = false;
                    app.Freq5.Enable = false;
                    
                    %app.Amp2.Enable = false;
                    app.Amp3.Enable = false;
                    app.Amp4.Enable = false;
                    app.Amp5.Enable = false;
                 case 'Sine'         
                    app.Freq1.Enable = true;
                    app.Freq2.Enable = true;
                    app.Freq3.Enable = true;
                    app.Freq4.Enable = true;
                    app.Freq5.Enable = true;
                    
                    app.Amp1.Enable = true;
                    app.Amp2.Enable = true;
                    app.Amp3.Enable = true;
                    app.Amp4.Enable = true;
                    app.Amp5.Enable = true;
                
             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NoiseSlider.Enable = false;
            app.amplifyIndex = 1;
        end

        % Value changed function: GenStBtn
        function GenBtnChanged(app, event)
            samplerate =  str2num(app.SampleRate.Value);
            value = app.GenStBtn.Value;    
            time = 0:1/samplerate:2;

            if (value)
                app.GenStBtn.Text = 'Stop';
                app.SampleRate.Enable = false;
                app.waveshape.Enable  = false;
                app.timesSlider.Enable = false;
                app.TakeaShotBtn.Enable = false;
         
            else
                app.GenStBtn.Text = 'Live Waves';
                app.SampleRate.Enable = true;
                app.waveshape.Enable  = true;
                app.timesSlider.Enable = true;
                app.TakeaShotBtn.Enable = true;
          
            end
           
            
            while get(app.GenStBtn,'Value')
                
                % Get everything from UI;
                [freq, ampt, samplerate, time, noise, type] = app.getAllValues();
                % original Shape
            
                signal = generator(type, time, samplerate, freq, ampt, noise, round(app.NoiseSlider.Value) );
             
                 if (app.HighPassButton.Value)           
                     %signal = highpassFt(samplerate, app.txtHighPass.Value, signal); 
                     [b,a]   = butter(round(app.FilterConstKnob.Value),app.txtHighPass.Value/(samplerate/2), 'high');
                     signal  = filter(b,a,signal);
                end
                
                if (app.LowPassButton.Value)
                    % signal = lowpassFt(samplerate, app.txtHighPass.Value, signal);
                    % signal = lowpass(signal, 220,samplerate, "ImpulseResponse",'iir','Steepness',0.5);
                    [b,a]   = butter(round(app.FilterConstKnob.Value),app.txtHighPass.Value/(samplerate/2), 'low');
                    signal  = filter(b,a,signal);
                end
                
                [fft, hz] = powerspec(time, samplerate, signal);
                
                minBund = min(freq)-20;
                if (minBund <=0)
                    minBund = 0;
                end
                
                set(app.SpecUIAxes, 'XLim', [minBund max(freq) + 20]);             
                plot(app.TimeUIAxespp,time, signal);
                plot(app.SpecUIAxes, hz, fft(1:length(hz)) * app.amplifyIndex);
                            
                pause(0.1);
            end
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectrumHAMKkuzarUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: SpectrumHAMKkuzarUIFigure
        function SpectrumHAMKkuzarUIFigureCloseRequest(app, event)
           
            app.GenStBtn.Value = false;
            delete(app)
        end

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxChanged(app, event)
                 
            value = app.NoiseCheckBox.Value;
            app.NoiseSlider.Enable = value;
          
        end

        % Value changed function: waveshape
        function waveshapeValueChanged(app, event)
            value = app.waveshape.Value;
            app.changeInputEnableStatue();
            switch (value)
                case 'Sine'            
                    app.amplifyIndex = 1;
                    app.TimeUIAxespp.XLim = [-450 450];
                case 'Square'                                
                    app.amplifyIndex = 260;                   
                    app.TimeUIAxespp.YLim = [-5 5];
                case 'Triangle'
                    if (app.NoiseCheckBox.Value)
                        app.amplifyIndex = 1;  
                    else
                        app.amplifyIndex = 0.5; 
                    end
                   
                case 'Saw'
                    if (app.NoiseCheckBox.Value)
                        app.amplifyIndex = 300;  
                    else
                        app.amplifyIndex = 0.6; 
                    end
                otherwise
                  
            end
        end

        % Button pushed function: TakeaShotBtn
        function TakeaShotBtnButtonPushed(app, event)
            samplerate =  str2num(app.SampleRate.Value);           
            time = 0:1/samplerate:2;

             % Get everything from UI;
                [freq, ampt, samplerate, time, noise, type] = app.getAllValues();
                % original Shape
                signal = generator(type, time, samplerate, freq, ampt, noise, round(app.NoiseSlider.Value));
          
                %filter
                if (app.HighPassButton.Value)           
                     %signal = highpassFt(samplerate, app.txtHighPass.Value, signal); 
                     [b,a]   = butter(round(app.FilterConstKnob.Value) ,app.txtHighPass.Value/(samplerate/2), 'high');
                     signal  = filter(b,a,signal);
                end
                
                if (app.LowPassButton.Value)
                    % signal = lowpassFt(samplerate, app.txtHighPass.Value, signal);
                    % signal = lowpass(signal, 220,samplerate, "ImpulseResponse",'iir','Steepness',0.5);
                    [b,a]   = butter(round(app.FilterConstKnob.Value) ,app.txtHighPass.Value/(samplerate/2), 'low');
                    signal  = filter(b,a,signal);
                end
                
                [fft, hz] = powerspec(time, samplerate, signal);
                
                minBund = min(freq)-20;
                if (minBund <=0)
                    minBund = 0;
                end
                
                set(app.SpecUIAxes, 'XLim', [minBund max(freq) + 20]);             
                plot(app.TimeUIAxespp,time, signal);
                plot(app.SpecUIAxes, hz, fft(1:length(hz)) * app.amplifyIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrumHAMKkuzarUIFigure = uifigure('Visible', 'off');
            app.SpectrumHAMKkuzarUIFigure.Name = 'Spectrum - HAMK - kuzar';
            app.SpectrumHAMKkuzarUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrumHAMKkuzarUIFigureCloseRequest, true);
            app.SpectrumHAMKkuzarUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpectrumHAMKkuzarUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GeneratorPanel = uipanel(app.LeftPanel);
            app.GeneratorPanel.Title = 'Generator';
            app.TabGroup = uitabgroup(app.GeneratorPanel);
            app.Fq1Tab = uitab(app.TabGroup);
            app.Fq1Tab.Title = 'Fq1';
            app.AmpEditFieldLabel = uilabel(app.Fq1Tab);
            app.AmpEditFieldLabel.Text = 'Amp.';
            app.Amp1 = uieditfield(app.Fq1Tab, 'numeric');
            app.Amp1.Limits = [0 100000];
            app.Amp1.Value = 80;
            app.FreqLabel = uilabel(app.Fq1Tab);
            app.FreqLabel.Text = 'Freq.';
            app.Freq1 = uieditfield(app.Fq1Tab, 'numeric');
            app.Freq1.Limits = [0 100000];
            app.Freq1.Value = 250;
            app.Fq2Tab = uitab(app.TabGroup);
            app.Fq2Tab.Title = 'Fq2';
            app.AmpEditField_2Label = uilabel(app.Fq2Tab);
            app.AmpEditField_2Label.Text = 'Amp.';
            app.Amp2 = uieditfield(app.Fq2Tab, 'numeric');
            app.Amp2.Limits = [0 100000];
            app.Amp2.Value = 40;
            app.FreqEditField_2Label = uilabel(app.Fq2Tab);
            app.FreqEditField_2Label.Text = 'Freq.';
            app.Freq2 = uieditfield(app.Fq2Tab, 'numeric');
            app.Freq2.Limits = [0 100000];
            app.Freq2.Value = 230;
            app.Fq3Tab = uitab(app.TabGroup);
            app.Fq3Tab.Title = 'Fq3';
            app.AmpEditField_3Label = uilabel(app.Fq3Tab);
            app.AmpEditField_3Label.Text = 'Amp.';
            app.Amp3 = uieditfield(app.Fq3Tab, 'numeric');
            app.Amp3.Limits = [0 100000];
            app.Amp3.Value = 90;
            app.FreqEditField_3Label = uilabel(app.Fq3Tab);
            app.FreqEditField_3Label.Text = 'Freq.';
            app.Freq3 = uieditfield(app.Fq3Tab, 'numeric');
            app.Freq3.Limits = [0 100000];
            app.Freq3.Value = 260;
            app.Fq4Tab = uitab(app.TabGroup);
            app.Fq4Tab.Title = 'Fq4';
            app.AmpEditField_4Label = uilabel(app.Fq4Tab);
            app.AmpEditField_4Label.Text = 'Amp.';
            app.Amp4 = uieditfield(app.Fq4Tab, 'numeric');
            app.Amp4.Limits = [0 100000];
            app.Amp4.Value = 60;
            app.FreqEditField_4Label = uilabel(app.Fq4Tab);
            app.FreqEditField_4Label.Text = 'Freq.';
            app.Freq4 = uieditfield(app.Fq4Tab, 'numeric');
            app.Freq4.Limits = [0 100000];
            app.Freq4.Value = 180;
            app.Fq5Tab = uitab(app.TabGroup);
            app.Fq5Tab.Title = 'Fq5';
            app.AmpEditField_4Label_2 = uilabel(app.Fq5Tab);
            app.AmpEditField_4Label_2.Text = 'Amp.';
            app.Amp5 = uieditfield(app.Fq5Tab, 'numeric');
            app.Amp5.Limits = [0 100000];
            app.Amp5.Value = 60;
            app.FreqEditField_4Label_2 = uilabel(app.Fq5Tab);
            app.FreqEditField_4Label_2.Text = 'Freq.';
            app.Freq5 = uieditfield(app.Fq5Tab, 'numeric');
            app.Freq5.Limits = [0 100000];
            app.Freq5.Value = 190;
            app.GenStBtn = uibutton(app.GeneratorPanel, 'state');
            app.GenStBtn.ValueChangedFcn = createCallbackFcn(app, @GenBtnChanged, true);
            app.GenStBtn.Text = 'Live Waves';
            app.NoiseCheckBox = uicheckbox(app.GeneratorPanel);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.SampleRateDropDownLabel = uilabel(app.GeneratorPanel);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.SampleRate = uidropdown(app.GeneratorPanel);
            app.SampleRate.Items = {'20', '50', '100', '500', '1000', '5000', '10000', '25000', '44100', '48000'};
            app.SampleRate.Value = '1000';
            app.NoiseSliderLabel = uilabel(app.GeneratorPanel);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.GeneratorPanel);
            app.NoiseSlider.Limits = [1 100];
            app.NoiseSlider.MajorTicks = [0 50 100];
            app.NoiseSlider.MajorTickLabels = {'0', '50', '100'};
            app.NoiseSlider.Value = 5;
            app.waveshapeDropDownLabel = uilabel(app.GeneratorPanel);
            app.waveshapeDropDownLabel.Text = 'wave shape';
            app.waveshape = uidropdown(app.GeneratorPanel);
            app.waveshape.Items = {'Sine', 'Square', 'Triangle', 'Saw'};
            app.waveshape.ValueChangedFcn = createCallbackFcn(app, @waveshapeValueChanged, true);
            app.waveshape.Value = 'Sine';
            app.timesSliderLabel = uilabel(app.GeneratorPanel);
            app.timesSliderLabel.Text = 'time(s)';
            app.timesSlider = uislider(app.GeneratorPanel);
            app.timesSlider.Limits = [0 2.5];
            app.timesSlider.Value = 1;
            app.TakeaShotBtn = uibutton(app.GeneratorPanel, 'push');
            app.TakeaShotBtn.ButtonPushedFcn = createCallbackFcn(app, @TakeaShotBtnButtonPushed, true);
            app.TakeaShotBtn.Text = 'Take a Shot';
            app.FitlersPanel = uipanel(app.LeftPanel);
            app.FitlersPanel.Title = 'Fitlers';
            app.txtHighPass = uieditfield(app.FitlersPanel, 'numeric');
            app.txtHighPass.Value = 200;
            app.FilterTypeButtonGroup = uibuttongroup(app.FitlersPanel);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.HighPassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighPassButton.Text = 'HighPass';
            app.LowPassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowPassButton.Text = 'LowPass';
            app.NoneButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.FilterConstKnobLabel = uilabel(app.FitlersPanel);
            app.FilterConstKnobLabel.Text = 'Filter Const';
            app.FilterConstKnob = uiknob(app.FitlersPanel, 'continuous');
            app.FilterConstKnob.Limits = [3 12];
            app.FilterConstKnob.MajorTicks = [3 4 5 6 7 8 9 10 11 12];
            app.FilterConstKnob.MinorTicks = [3 4 5 6 7 8 9 10 11 12];
            app.FilterConstKnob.Value = 7;
            app.RightPanel = uipanel(app.GridLayout);
            app.TimeUIAxespp = uiaxes(app.RightPanel);
            title(app.TimeUIAxespp, 'Time')
            xlabel(app.TimeUIAxespp, 'Time')
            ylabel(app.TimeUIAxespp, 'Amplitude')
            app.TimeUIAxespp.YLim = [-450 450];
            app.SpecUIAxes = uiaxes(app.RightPanel);
            title(app.SpecUIAxes, 'Spectrum')
            xlabel(app.SpecUIAxes, 'Frequency(Hz)')
            ylabel(app.SpecUIAxes, 'Power')
            app.SpecUIAxes.XLim = [0 1];
            app.SpecUIAxes.YLim = [0 500];
            % Show the figure after all components are created
            app.SpectrumHAMKkuzarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectrum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrumHAMKkuzarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrumHAMKkuzarUIFigure)
        end
    end
end